classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Soal1Tab                matlab.ui.container.Tab
        InputMaskTextAreaLabel  matlab.ui.control.Label
        srcEditField            matlab.ui.control.EditField
        PilihGambarButton       matlab.ui.control.Button
        InputMaskTextArea       matlab.ui.control.TextArea
        MaskinvalidLabel        matlab.ui.control.Label
        srcEditFieldLabel       matlab.ui.control.Label
        UIAxes_Input            matlab.ui.control.UIAxes
        UIAxes_Output           matlab.ui.control.UIAxes
        UIAxes_Matlab           matlab.ui.control.UIAxes
        Soal2Tab                matlab.ui.container.Tab
        Soal3Tab                matlab.ui.container.Tab
        Soal4Tab                matlab.ui.container.Tab
        Soal5Tab                matlab.ui.container.Tab
        Soal6Tab                matlab.ui.container.Tab
        Soal7Tab                matlab.ui.container.Tab
    end

    
    methods (Access = private)
        function [img, fullPath] = browseImage(app)
           [file, path] = uigetfile('*.png;*.jpg;*.bmp;*.tif', 'Pilih Gambar');

            
            if isequal(file, 0)  % User clicked cancel
                img = [];        % Return empty image
                fullPath = '';   % Return empty path
            else
                fullPath = fullfile(path, file);  % Concatenate path and file
                img = imread(fullPath);            % Read the image file
            end

            drawnow;
            figure(app.UIFigure)
        end
        
        function section1_apply_convolution(app)
            if app.srcEditField.Value == ""
                cla(app.UIAxes_Output, 'reset');
                cla(app.UIAxes_Matlab, 'reset');
            else
                [mask, maskValid] = parse_mask_input(app.InputMaskTextArea.Value);
                if ~maskValid
                    app.MaskinvalidLabel.Visible = 'on';
                    return;
                end
                app.MaskinvalidLabel.Visible = 'off';
                img = imread(app.srcEditField.Value);
                conv_img = image_convolution(img, mask);

                mask_sum = sum(mask(:));
                matlab_conv_img = imfilter(im2double(img), mask, 'replicate', 'conv');
                if mask_sum ~= 0
                    matlab_conv_img = matlab_conv_img / mask_sum;
                end
                matlab_conv_img = im2uint8(matlab_conv_img);

                imshow(conv_img, 'Parent', app.UIAxes_Output);
                imshow(matlab_conv_img, 'Parent', app.UIAxes_Matlab);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes_Input);
            section1_apply_convolution(app);
        end

        % Value changed function: InputMaskTextArea
        function InputMaskTextAreaValueChanged(app, event)
            section1_apply_convolution(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Soal1Tab = uitab(app.TabGroup);
            app.Soal1Tab.Title = 'Soal 1';
            app.UIAxes_Matlab = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Matlab, 'Matlab')
            app.UIAxes_Matlab.XTick = [];
            app.UIAxes_Matlab.YTick = [];
            app.UIAxes_Output = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Output, 'Output')
            app.UIAxes_Output.XTick = [];
            app.UIAxes_Output.YTick = [];
            app.UIAxes_Input = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Input, 'Input')
            app.UIAxes_Input.XTick = [];
            app.UIAxes_Input.YTick = [];
            app.srcEditFieldLabel = uilabel(app.Soal1Tab);
            app.srcEditFieldLabel.Text = 'src';
            app.MaskinvalidLabel = uilabel(app.Soal1Tab);
            app.MaskinvalidLabel.Visible = 'off';
            app.MaskinvalidLabel.Text = 'Mask invalid';
            app.InputMaskTextArea = uitextarea(app.Soal1Tab);
            app.InputMaskTextArea.ValueChangedFcn = createCallbackFcn(app, @InputMaskTextAreaValueChanged, true);
            app.InputMaskTextArea.Value = {'0 -1 0'; '-1 4 -1'; '0 -1 0'};
            app.PilihGambarButton = uibutton(app.Soal1Tab, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.srcEditField = uieditfield(app.Soal1Tab, 'text');
            app.InputMaskTextAreaLabel = uilabel(app.Soal1Tab);
            app.InputMaskTextAreaLabel.Text = 'Input Mask';
            app.Soal2Tab = uitab(app.TabGroup);
            app.Soal2Tab.Title = 'Soal 2';
            app.Soal3Tab = uitab(app.TabGroup);
            app.Soal3Tab.Title = 'Soal 3';
            app.Soal4Tab = uitab(app.TabGroup);
            app.Soal4Tab.Title = 'Soal 4';
            app.Soal5Tab = uitab(app.TabGroup);
            app.Soal5Tab.Title = 'Soal 5';
            app.Soal6Tab = uitab(app.TabGroup);
            app.Soal6Tab.Title = 'Soal 6';
            app.Soal7Tab = uitab(app.TabGroup);
            app.Soal7Tab.Title = 'Soal 7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end